classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        InverseKinematicsButton    matlab.ui.control.Button
        YawEditField               matlab.ui.control.NumericEditField
        YawEditFieldLabel          matlab.ui.control.Label
        PitchEditField             matlab.ui.control.NumericEditField
        PitchEditFieldLabel        matlab.ui.control.Label
        RollEditField              matlab.ui.control.NumericEditField
        RollEditFieldLabel         matlab.ui.control.Label
        ZEditField                 matlab.ui.control.NumericEditField
        ZEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        XEditFieldLabel            matlab.ui.control.Label
        Joint3Slider               matlab.ui.control.Slider
        Joint4Slider               matlab.ui.control.Slider
        Joint4SliderLabel          matlab.ui.control.Label
        Joint3SliderLabel          matlab.ui.control.Label
        Joint5Slider               matlab.ui.control.Slider
        Joint5SliderLabel          matlab.ui.control.Label
        Joint6Slider               matlab.ui.control.Slider
        Joint6SliderLabel          matlab.ui.control.Label
        Joint2Slider               matlab.ui.control.Slider
        Joint2SliderLabel          matlab.ui.control.Label
        EMERGENCYSTOPButton        matlab.ui.control.Button
        DISENGAGEESTOPButton       matlab.ui.control.Button
        EditField_6                matlab.ui.control.NumericEditField
        EditField_5                matlab.ui.control.NumericEditField
        EditField_4                matlab.ui.control.NumericEditField
        EditField_3                matlab.ui.control.NumericEditField
        EditField_2                matlab.ui.control.NumericEditField
        EditField                  matlab.ui.control.NumericEditField
        RESUMEButton               matlab.ui.control.Button
        Lamp                       matlab.ui.control.Lamp
        Joint1Slider               matlab.ui.control.Slider
        Joint1SliderLabel          matlab.ui.control.Label
        MakeLoadedUR3eMoveButton   matlab.ui.control.Button
        MakeLoadedWidowMoveButton  matlab.ui.control.Button
        plot                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % place your class properties here
        modelWidow % Where I will put the loaded Widow
        modelUR3e % Where I will put the loaded UR3e
        qz = [0     0     0     0     0     0];

        qrWidow = deg2rad([-15, 42.3, -5.9, -6.17, 52.4, -50.3]);
        qrUR3e = deg2rad([0, -20, 40, -110, -90, 0]);

        Widowfin1 = deg2rad([-180, -14.6, 26.9, 2.18, 76.3, -24.4]);
        UR3efin2 = deg2rad([-180, -50, 90, -130, -90, 0]);

        eStopActivated = false; % Not working

        valueX;
        valueY;
        valueZ;
    end

    methods (Access = private)

        function updateJointConfiguration(app)
            % Define the end-effector position using the x, y, and z values
            endEffectorPosition = [app.valueX; app.valueY; app.valueZ];

            % Perform inverse kinematics to obtain the joint configuration
            currentPos = app.modelWidow.model.getpos();
            jointConfigurations = app.modelWidow.model.ikcon(transl(endEffectorPosition), currentPos);

            % Update the joint configuration in the GUI
            app.EditField.Value = jointConfigurations(1); % Update for joint 1
            app.EditField_2.Value = jointConfigurations(2); % Update for joint 2
            app.EditField_3.Value = jointConfigurations(3); % Update for joint 3
            app.EditField_4.Value = jointConfigurations(4); % Update for joint 4
            app.EditField_5.Value = jointConfigurations(5); % Update for joint 5
            app.EditField_6.Value = jointConfigurations(6); % Update for joint 6
        end

        % function updateXYZEditFields(app, currentPos)
        %     % Extract the x, y, z values from the current position
        %     x = currentPos(1);
        %     y = currentPos(2);
        %     z = currentPos(3);
        % 
        %     % Update the x, y, z edit fields
        %     app.XEditField.Value = x;
        %     app.YEditField.Value = y;
        %     app.ZEditField.Value = z;
        % end
        
        function tau = zeroTorque(~, ~, ~)
            % Return zero torque for all joints
            tau = zeros(1, app.modelWidow.model.n);  % Use the robot model from your app
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;
            hold on;

            % Make, save and plot some robots
            app.modelUR3e = UR3e;
            app.modelUR3e.model.base = transl([-0.5,0,0]);
            app.modelUR3e.model.animate(app.qrUR3e);

            app.modelWidow = WidowX250;
            app.modelWidow.model.base = transl([0.5,0,0]);
            app.modelWidow.model.animate(app.qrWidow);

            drawnow();

            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end

            % Turn off this load button and enable the move button
            app.MakeLoadedWidowMoveButton.Enable = true;
            app.MakeLoadedUR3eMoveButton.Enable = true;

        end

        % Button pushed function: MakeLoadedWidowMoveButton
        function MakeLoadedWidowMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrWidow, app.Widowfin1, 20);

            for robotStepIndex = 1:size(qmatrix,1)
                app.modelWidow.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Button pushed function: MakeLoadedUR3eMoveButton
        function MakeLoadedUR3eMoveButtonPushed(app, event)
            qmatrix = jtraj(app.qrUR3e, app.UR3efin2, 20);

            for robotStepIndex = 1:size(qmatrix,1)
                app.modelUR3e.model.animate(qmatrix(robotStepIndex,:));
                drawnow;

            end
        end

        % Value changing function: Joint1Slider
        function Joint1SliderValueChanging(app, event)
            changingValue_deg = event.Value; % Input value in degrees
            changingValue_rad = deg2rad(changingValue_deg); % Convert degrees to radians
            app.EditField.Value = changingValue_deg; % Set the edit field value to the slider value in degrees
            currentPos = app.modelWidow.model.getpos();
            currentPos(1) = changingValue_rad;
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value_deg = app.EditField.Value; % Input value in degrees

            % Convert degrees to radians
            value_rad = deg2rad(value_deg);

            if value_rad < app.Joint1Slider.Limits(1) || value_rad > app.Joint1Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value_rad = max(app.Joint1Slider.Limits(1), min(value_rad, app.Joint1Slider.Limits(2)));
                value_deg = rad2deg(value_rad); % Convert back to degrees for display purposes
                app.EditField.Value = value_deg; % Display the value in degrees
            end

            app.Joint1Slider.Value = value_deg; % Set the slider value to the edit field value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(1) = value_rad; % Use the value in radians for further processing
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);

        end

        % Value changing function: Joint2Slider
        function Joint2SliderValueChanging(app, event)
            changingValue_deg = event.Value; % Input value in degrees
            changingValue_rad = deg2rad(changingValue_deg); % Convert degrees to radians

            app.EditField_2.Value = changingValue_deg; % Set the edit field value to the slider value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(2) = changingValue_rad;
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value_deg = app.EditField_2.Value; % Input value in degrees

            % Convert degrees to radians
            value_rad = deg2rad(value_deg);

            if value_rad < app.Joint2Slider.Limits(1) || value_rad > app.Joint2Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value_rad = max(app.Joint2Slider.Limits(1), min(value_rad, app.Joint2Slider.Limits(2)));
                value_deg = rad2deg(value_rad); % Convert back to degrees for display purposes
                app.EditField_2.Value = value_deg; % Display the value in degrees
            end

            app.Joint2Slider.Value = value_deg; % Set the slider value to the edit field value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(2) = value_rad; % Use the value in radians for further processing
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changing function: Joint3Slider
        function Joint3SliderValueChanging(app, event)
            changingValue_deg = event.Value; % Input value in degrees
            changingValue_rad = deg2rad(changingValue_deg); % Convert degrees to radians

            app.EditField_3.Value = changingValue_deg; % Set the edit field value to the slider value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(3) = changingValue_rad;
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value_deg = app.EditField_3.Value; % Input value in degrees

            % Convert degrees to radians
            value_rad = deg2rad(value_deg);

            if value_rad < app.Joint3Slider.Limits(1) || value_rad > app.Joint3Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value_rad = max(app.Joint3Slider.Limits(1), min(value_rad, app.Joint3Slider.Limits(2)));
                value_deg = rad2deg(value_rad); % Convert back to degrees for display purposes
                app.EditField_3.Value = value_deg; % Display the value in degrees
            end

            app.Joint3Slider.Value = value_deg; % Set the slider value to the edit field value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(3) = value_rad; % Use the value in radians for further processing
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changing function: Joint4Slider
        function Joint4SliderValueChanging(app, event)
            changingValue_deg = event.Value; % Input value in degrees
            changingValue_rad = deg2rad(changingValue_deg); % Convert degrees to radians

            app.EditField_4.Value = changingValue_deg; % Set the edit field value to the slider value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(4) = changingValue_rad;
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value_deg = app.EditField_4.Value; % Input value in degrees

            % Convert degrees to radians
            value_rad = deg2rad(value_deg);

            if value_rad < app.Joint4Slider.Limits(1) || value_rad > app.Joint4Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value_rad = max(app.Joint4Slider.Limits(1), min(value_rad, app.Joint4Slider.Limits(2)));
                value_deg = rad2deg(value_rad); % Convert back to degrees for display purposes
                app.EditField_4.Value = value_deg; % Display the value in degrees
            end

            app.Joint4Slider.Value = value_deg; % Set the slider value to the edit field value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(4) = value_rad; % Use the value in radians for further processing
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changing function: Joint5Slider
        function Joint5SliderValueChanging(app, event)
            changingValue_deg = event.Value; % Input value in degrees
            changingValue_rad = deg2rad(changingValue_deg); % Convert degrees to radians

            app.EditField_5.Value = changingValue_deg; % Set the edit field value to the slider value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(5) = changingValue_rad;
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value_deg = app.EditField_5.Value; % Input value in degrees

            % Convert degrees to radians
            value_rad = deg2rad(value_deg);

            if value_rad < app.Joint5Slider.Limits(1) || value_rad > app.Joint5Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value_rad = max(app.Joint5Slider.Limits(1), min(value_rad, app.Joint5Slider.Limits(2)));
                value_deg = rad2deg(value_rad); % Convert back to degrees for display purposes
                app.EditField_5.Value = value_deg; % Display the value in degrees
            end

            app.Joint5Slider.Value = value_deg; % Set the slider value to the edit field value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(5) = value_rad; % Use the value in radians for further processing
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changing function: Joint6Slider
        function Joint6SliderValueChanging(app, event)
            changingValue_deg = event.Value; % Input value in degrees
            changingValue_rad = deg2rad(changingValue_deg); % Convert degrees to radians

            app.EditField_6.Value = changingValue_deg; % Set the edit field value to the slider value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(6) = changingValue_rad;
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changed function: EditField_6
        function EditField_6ValueChanged(app, event)
            value_deg = app.EditField_6.Value; % Input value in degrees

            % Convert degrees to radians
            value_rad = deg2rad(value_deg);

            if value_rad < app.Joint6Slider.Limits(1) || value_rad > app.Joint6Slider.Limits(2)
                % Ensure the value remains within the slider limits
                value_rad = max(app.Joint6Slider.Limits(1), min(value_rad, app.Joint6Slider.Limits(2)));
                value_deg = rad2deg(value_rad); % Convert back to degrees for display purposes
                app.EditField_6.Value = value_deg; % Display the value in degrees
            end

            app.Joint6Slider.Value = value_deg; % Set the slider value to the edit field value in degrees

            currentPos = app.modelWidow.model.getpos();
            currentPos(6) = value_rad; % Use the value in radians for further processing
            app.modelWidow.model.animate(currentPos);

            % Update the x, y, z edit fields
            % updateXYZEditFields(app, currentPos);
        end

        % Value changed function: RollEditField
        function RollEditFieldValueChanged(app, event)
            % Get the current joint configurations
            currentPos = app.modelWidow.model.getpos();

            % Convert to a transformation matrix
            T = getTransformationMatrix(currentPos);

            % Extract the roll from the transformation matrix using tr2rpy
            [roll, ~, ~] = tr2rpy(T);

            % Convert the roll to degrees for display purposes
            roll_deg = rad2deg(roll);

            % Display the roll in the NumericEditField
            app.RollEditField.Value = roll_deg;
        end

        % Value changed function: PitchEditField
        function PitchEditFieldValueChanged(app, event)
            value = app.PitchEditField.Value;

        end

        % Value changed function: YawEditField
        function YawEditFieldValueChanged(app, event)
            value = app.YawEditField.Value;

        end

        % Button pushed function: InverseKinematicsButton
        function InverseKinematicsButtonPushed(app, event)
            % Define end-effector position
            endEffectorPosition = [app.valueX; app.valueY; app.valueZ];

            % Perform inverse kinematics to obtain the joint configuration
            currentPos = app.modelWidow.model.getpos();
            jointConfigurations = app.modelWidow.model.ikcon(transl(endEffectorPosition), currentPos);

            % Update the joint configuration in the GUI
            app.EditField.Value = jointConfigurations(1); % Update for joint 1
            app.EditField_2.Value = jointConfigurations(2); % Update for joint 2
            app.EditField_3.Value = jointConfigurations(3); % Update for joint 3
            app.EditField_4.Value = jointConfigurations(4); % Update for joint 4
            app.EditField_5.Value = jointConfigurations(5); % Update for joint 5
            app.EditField_6.Value = jointConfigurations(6); % Update for joint 6

            % Update animation
            app.modelWidow.model.animate(jointConfigurations);

        end

        % Value changed function: XEditField
        function XEditFieldValueChanged(app, event)
            app.valueX = app.XEditField.Value;
            updateJointConfiguration(app);

        end

        % Value changed function: YEditField
        function YEditFieldValueChanged(app, event)
            app.valueY = app.YEditField.Value;
            updateJointConfiguration(app);

        end

        % Value changed function: ZEditField
        function ZEditFieldValueChanged(app, event)
            app.valueZ = app.ZEditField.Value;
            updateJointConfiguration(app);

        end

        % Button pushed function: EMERGENCYSTOPButton
        function EMERGENCYSTOPButtonPushed(app, event)
            app.eStopActivated = true;
            % currentWidowPos = app.modelWidow.model.getpos();
            % currentUR3Pos = app.modelUR3e.model.getpos();
            
            % Define the time interval and initial joint state
            T = 10;  % Time interval
            q0 = app.modelUR3e.model.getpos();  % Initial joint positions
            qd0 = zeros(1, app.modelUR3e.model.n); % Initial joint velocities
            
            % Integrate the dynamics with the zero torque control function
            [T, q, ~] = app.modelUR3e.model.fdyn(T, @zeroTorque, q0, qd0);
            app.modelUR3e.model.qd = zeros(1, app.modelUR3e.model.n);
            % zeroVelocities = zeros(1, app.modelWidow.model.n);
            % 
            % 
            % for j = 1:app.modelWidow.model.n
            %     app.modelWidow.link(j).qd = zeroVelocities(j);
            % end            

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'WidowX 250 GUI';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.XLim = [-1.1 1.1];
            app.plot.YLim = [-1.1 1.1];
            app.plot.ZLim = [0 1];
            app.plot.Tag = 'axes1';
            app.MakeLoadedWidowMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedWidowMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedWidowMoveButtonPushed, true);
            app.MakeLoadedWidowMoveButton.Text = 'Make Loaded Widow Move';
            app.MakeLoadedUR3eMoveButton = uibutton(app.figure1, 'push');
            app.MakeLoadedUR3eMoveButton.ButtonPushedFcn = createCallbackFcn(app, @MakeLoadedUR3eMoveButtonPushed, true);
            app.MakeLoadedUR3eMoveButton.Text = 'Make Loaded UR3e Move';
            app.Joint1SliderLabel = uilabel(app.figure1);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.figure1);
            app.Joint1Slider.Limits = [-180 180];
            app.Joint1Slider.MajorTicks = [-180 0 180];
            app.Joint1Slider.MajorTickLabels = {'-180', '0', '180'};
            app.Joint1Slider.ValueChangingFcn = createCallbackFcn(app, @Joint1SliderValueChanging, true);
            app.Joint1Slider.MinorTicks = 0;
            app.Lamp = uilamp(app.figure1);
            app.RESUMEButton = uibutton(app.figure1, 'push');
            app.RESUMEButton.Text = 'RESUME';
            app.EditField = uieditfield(app.figure1, 'numeric');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField_2 = uieditfield(app.figure1, 'numeric');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_3 = uieditfield(app.figure1, 'numeric');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.EditField_4 = uieditfield(app.figure1, 'numeric');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.EditField_5 = uieditfield(app.figure1, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.EditField_6 = uieditfield(app.figure1, 'numeric');
            app.EditField_6.ValueChangedFcn = createCallbackFcn(app, @EditField_6ValueChanged, true);
            app.DISENGAGEESTOPButton = uibutton(app.figure1, 'push');
            app.DISENGAGEESTOPButton.Text = 'DISENGAGE E-STOP';
            app.EMERGENCYSTOPButton = uibutton(app.figure1, 'push');
            app.EMERGENCYSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @EMERGENCYSTOPButtonPushed, true);
            app.EMERGENCYSTOPButton.Text = 'EMERGENCY STOP';
            app.Joint2SliderLabel = uilabel(app.figure1);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.figure1);
            app.Joint2Slider.Limits = [-108 114];
            app.Joint2Slider.MajorTicks = [-108 0 114];
            app.Joint2Slider.ValueChangingFcn = createCallbackFcn(app, @Joint2SliderValueChanging, true);
            app.Joint2Slider.MinorTicks = 0;
            app.Joint6SliderLabel = uilabel(app.figure1);
            app.Joint6SliderLabel.Text = 'Joint 6';
            app.Joint6Slider = uislider(app.figure1);
            app.Joint6Slider.Limits = [-180 180];
            app.Joint6Slider.MajorTicks = [-180 0 180];
            app.Joint6Slider.ValueChangingFcn = createCallbackFcn(app, @Joint6SliderValueChanging, true);
            app.Joint6Slider.MinorTicks = 0;
            app.Joint5SliderLabel = uilabel(app.figure1);
            app.Joint5SliderLabel.Text = 'Joint 5';
            app.Joint5Slider = uislider(app.figure1);
            app.Joint5Slider.Limits = [-180 180];
            app.Joint5Slider.MajorTicks = [-180 0 180];
            app.Joint5Slider.ValueChangingFcn = createCallbackFcn(app, @Joint5SliderValueChanging, true);
            app.Joint5Slider.MinorTicks = 0;
            app.Joint3SliderLabel = uilabel(app.figure1);
            app.Joint3SliderLabel.Text = 'Joint 3';
            app.Joint4SliderLabel = uilabel(app.figure1);
            app.Joint4SliderLabel.Text = 'Joint 4';
            app.Joint4Slider = uislider(app.figure1);
            app.Joint4Slider.Limits = [-100 123];
            app.Joint4Slider.MajorTicks = [-100 0 123];
            app.Joint4Slider.ValueChangingFcn = createCallbackFcn(app, @Joint4SliderValueChanging, true);
            app.Joint4Slider.MinorTicks = 0;
            app.Joint3Slider = uislider(app.figure1);
            app.Joint3Slider.Limits = [-123 92];
            app.Joint3Slider.MajorTicks = [-123 0 92];
            app.Joint3Slider.ValueChangingFcn = createCallbackFcn(app, @Joint3SliderValueChanging, true);
            app.Joint3Slider.MinorTicks = 0;
            app.XEditFieldLabel = uilabel(app.figure1);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.figure1, 'numeric');
            app.XEditField.ValueChangedFcn = createCallbackFcn(app, @XEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.figure1);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.figure1, 'numeric');
            app.YEditField.ValueChangedFcn = createCallbackFcn(app, @YEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.figure1);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.figure1, 'numeric');
            app.ZEditField.ValueChangedFcn = createCallbackFcn(app, @ZEditFieldValueChanged, true);
            app.RollEditFieldLabel = uilabel(app.figure1);
            app.RollEditFieldLabel.Text = 'Roll';
            app.RollEditField = uieditfield(app.figure1, 'numeric');
            app.RollEditField.ValueChangedFcn = createCallbackFcn(app, @RollEditFieldValueChanged, true);
            app.PitchEditFieldLabel = uilabel(app.figure1);
            app.PitchEditFieldLabel.Text = 'Pitch';
            app.PitchEditField = uieditfield(app.figure1, 'numeric');
            app.PitchEditField.ValueChangedFcn = createCallbackFcn(app, @PitchEditFieldValueChanged, true);
            app.YawEditFieldLabel = uilabel(app.figure1);
            app.YawEditFieldLabel.Text = 'Yaw';
            app.YawEditField = uieditfield(app.figure1, 'numeric');
            app.YawEditField.ValueChangedFcn = createCallbackFcn(app, @YawEditFieldValueChanged, true);
            app.InverseKinematicsButton = uibutton(app.figure1, 'push');
            app.InverseKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @InverseKinematicsButtonPushed, true);
            app.InverseKinematicsButton.Text = 'Inverse Kinematics';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end